classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        HelpButton                   matlab.ui.control.Button
        SoftmaxCheckBox              matlab.ui.control.CheckBox
        NumberoflayersDropDown       matlab.ui.control.DropDown
        NumberoflayersDropDownLabel  matlab.ui.control.Label
        TrainNetworkButton           matlab.ui.control.Button
        TestNetworkButton            matlab.ui.control.Button
        Activationfunction2ndlayerDropDown  matlab.ui.control.DropDown
        Activationfunction2ndlayerDropDownLabel  matlab.ui.control.Label
        Activationfunction1stlayerDropDown  matlab.ui.control.DropDown
        Activationfunction1stlayerDropDownLabel  matlab.ui.control.Label
        TextArea_5                   matlab.ui.control.TextArea
        TextArea_4                   matlab.ui.control.TextArea
        TextArea_3                   matlab.ui.control.TextArea
        TextArea                     matlab.ui.control.TextArea
        NoButton                     matlab.ui.control.Button
        YesButton                    matlab.ui.control.Button
        FiltersDropDown              matlab.ui.control.DropDown
        FiltersDropDownLabel         matlab.ui.control.Label
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end

    
    properties (Access = private)
        architecture='1L'; %arquitetura da rede neuronal
        f_act=["hardlim"]; %função de ativação
        option='test'; %inicializar variável option
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NumberoflayersDropDown.Items={'1'};
            app.Activationfunction1stlayerDropDown.Visible="on";
            app.Activationfunction1stlayerDropDownLabel.Visible="on";
            app.Activationfunction2ndlayerDropDown.Visible="off";
            app.Activationfunction2ndlayerDropDownLabel.Visible="off";
            app.FiltersDropDown.Visible="off";
            app.FiltersDropDownLabel.Visible="off";

        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            app.FiltersDropDown.Visible="off";
            app.FiltersDropDownLabel.Visible="off";
            app.NumberoflayersDropDown.Items={'1','2'};
            app.Activationfunction2ndlayerDropDown.Visible="off";
            app.Activationfunction2ndlayerDropDownLabel.Visible="off";
            app.SoftmaxCheckBox.Visible="on";
            app.NumberoflayersDropDown.Enable="on";
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            app.architecture="associative";
           app.FiltersDropDown.Visible="on";
           app.FiltersDropDownLabel.Visible="on";
           app.NumberoflayersDropDown.Items={'1'};
           app.Activationfunction2ndlayerDropDown.Visible="off";
           app.Activationfunction2ndlayerDropDownLabel.Visible="off";
           app.Activationfunction1stlayerDropDown.Items={'hardlim','purelin','logsig'};
           app.SoftmaxCheckBox.Visible="off";
           app.NumberoflayersDropDown.Enable="off";
        end

        % Value changed function: NumberoflayersDropDown
        function NumberoflayersDropDownValueChanged(app, event)
            value = app.NumberoflayersDropDown.Value;
            
            switch value

                case '1'
                    app.Activationfunction1stlayerDropDown.Visible="on";
                    app.Activationfunction1stlayerDropDownLabel.Visible="on";
                    app.Activationfunction2ndlayerDropDown.Visible="off";
                    app.Activationfunction2ndlayerDropDownLabel.Visible="off";
                    app.Activationfunction1stlayerDropDown.Items={'hardlim','purelin','logsig'};
                    app.SoftmaxCheckBox.Visible="on";

                   
                case '2'
                    app.Activationfunction1stlayerDropDown.Visible="on";
                    app.Activationfunction1stlayerDropDownLabel.Visible="on";
                    app.Activationfunction2ndlayerDropDown.Visible="on";
                    app.Activationfunction2ndlayerDropDownLabel.Visible="on";
                    app.Activationfunction1stlayerDropDown.Items={'purelin','logsig'};
                    app.Activationfunction2ndlayerDropDown.Items={'purelin','logsig'};
                    app.SoftmaxCheckBox.Visible="off";

            end

            
            if strcmp(value, '1')

                app.architecture="1L";

                if strcmp(app.Activationfunction1stlayerDropDown.Value, 'hardlim')
                    act1 = "hardlim";
                elseif strcmp(app.Activationfunction1stlayerDropDown.Value, 'purelin')
                    act1 = "purelin";
                elseif strcmp(app.Activationfunction1stlayerDropDown.Value, 'logsig')
                    act1 = "logsig";
                end
                
                app.f_act = [act1];
                
            elseif strcmp(value, '2')

                app.architecture="2L";
                
                if strcmp(app.Activationfunction1stlayerDropDown.Value, 'purelin')
                    act1 = "purelin";
                elseif strcmp(app.Activationfunction1stlayerDropDown.Value, 'logsig')
                    act1 = "logsig";
                end

                if strcmp(app.Activationfunction2ndlayerDropDown.Value, 'purelin')
                    act2 = "purelin";
                elseif strcmp(app.Activationfunction2ndlayerDropDown.Value, 'logsig')
                    act2 = "logsig";
                end
                
                app.f_act = [act1, act2];
            end
                    
        end

        % Value changed function: SoftmaxCheckBox
        function SoftmaxCheckBoxValueChanged(app, event)
            value = app.SoftmaxCheckBox.Value;
            
            switch value
                case 1
                    app.Activationfunction2ndlayerDropDown.Visible="off";
                    app.Activationfunction2ndlayerDropDownLabel.Visible="off";
                    app.Activationfunction1stlayerDropDown.Visible="on";
                    app.Activationfunction1stlayerDropDownLabel.Visible="on";
                    app.Activationfunction1stlayerDropDown.Items={'hardlim','purelin','logsig'};
                    
                    app.architecture="1LSft";
                case 0
                    app.Activationfunction1stlayerDropDown.Items={'hardlim','purelin','logsig'};
            end
                

        end

        % Value changed function: FiltersDropDown
        function FiltersDropDownValueChanged(app, event)
            value = app.FiltersDropDown.Value;
     

             if strcmp(value, 'Binary Perceptron')

                app.architecture="perceptron";
                app.NumberoflayersDropDown.Value='1';

                if strcmp(app.Activationfunction1stlayerDropDown.Value, 'hardlim')
                    act1 = "hardlim";
                elseif strcmp(app.Activationfunction1stlayerDropDown.Value, 'purelin')
                    act1 = "purelin";
                elseif strcmp(app.Activationfunction1stlayerDropDown.Value, 'logsig')
                    act1 = "logsig";
                end
                
                app.f_act = [act1];
                
            elseif strcmp(value, 'Associative Memory')

                app.architecture="associative";
                app.NumberoflayersDropDown.Value='1';
                
                if strcmp(app.Activationfunction1stlayerDropDown.Value, 'purelin')
                    act1 = "purelin";
                elseif strcmp(app.Activationfunction1stlayerDropDown.Value, 'logsig')
                    act1 = "logsig";
                elseif strcmp(app.Activationfunction1stlayerDropDown.Value, 'hardlim')
                    act1 = "hardlim";
                end

                app.f_act = [act1];
            end
      
        end

        % Value changed function: Activationfunction1stlayerDropDown
        function Activationfunction1stlayerDropDownValueChanged(app, event)
            value = app.Activationfunction1stlayerDropDown.Value;
            
            if strcmp(value,'hardlim')
                app.f_act(1)="hardlim";
            elseif strcmp(value,'purelin')
                app.f_act(1)="purelin";
            elseif strcmp(value,'logsig')
                app.f_act(1)="logsig";
            end

                
        end

        % Value changed function: Activationfunction2ndlayerDropDown
        function Activationfunction2ndlayerDropDownValueChanged(app, event)
            value = app.Activationfunction2ndlayerDropDown.Value;
            
            if strcmp(value,'purelin')
                app.f_act(2)="purelin";
            elseif strcmp(value,'logsig')
                app.f_act(2)="logsig";
            end
        end

        % Button pushed function: TrainNetworkButton
        function TrainNetworkButtonPushed(app, event)
            app.option='train';
            disp(app.architecture);
            disp(app.f_act);
            train_classifier(app.option,app.architecture,app.f_act);
        end

        % Button pushed function: TestNetworkButton
        function TestNetworkButtonPushed(app, event)
            disp(app.architecture);
            disp(app.f_act);
            app.option='test';
            train_classifier(app.option,app.architecture,app.f_act);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            msgbox(["1º-Escolha se deseja um filtro, caso queira, opte por um deles"; ...
                "2º-Selecione o número de camadas pretendidas"; ...
                "3º-Selecione a(as) função(ões) de ativação para as camadas escolhidas."],"Help","modal");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FiltersDropDownLabel = uilabel(app.UIFigure);
            app.FiltersDropDownLabel.Text = 'Filters';
            app.FiltersDropDown = uidropdown(app.UIFigure);
            app.FiltersDropDown.Items = {'Binary Perceptron', 'Associative Memory'};
            app.FiltersDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltersDropDownValueChanged, true);
            app.FiltersDropDown.Value = 'Associative Memory';
            app.YesButton = uibutton(app.UIFigure, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.NoButton = uibutton(app.UIFigure, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'                Filters                 '};
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'             OCR – Optical Character Recognition'};
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Value = {'Duarte Ferreira (2020235393) | Cristiana Azevedo (2020221121)'};
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_5.Value = {'             Classifier'};
            app.Activationfunction1stlayerDropDownLabel = uilabel(app.UIFigure);
            app.Activationfunction1stlayerDropDownLabel.Text = 'Activation function 1st layer';
            app.Activationfunction1stlayerDropDown = uidropdown(app.UIFigure);
            app.Activationfunction1stlayerDropDown.Items = {'hardlim', 'purelin', 'logisg'};
            app.Activationfunction1stlayerDropDown.ValueChangedFcn = createCallbackFcn(app, @Activationfunction1stlayerDropDownValueChanged, true);
            app.Activationfunction1stlayerDropDown.Value = 'hardlim';
            app.Activationfunction2ndlayerDropDownLabel = uilabel(app.UIFigure);
            app.Activationfunction2ndlayerDropDownLabel.Text = 'Activation function 2nd layer';
            app.Activationfunction2ndlayerDropDown = uidropdown(app.UIFigure);
            app.Activationfunction2ndlayerDropDown.Items = {'hardlim', 'purelin', 'logisg'};
            app.Activationfunction2ndlayerDropDown.ValueChangedFcn = createCallbackFcn(app, @Activationfunction2ndlayerDropDownValueChanged, true);
            app.Activationfunction2ndlayerDropDown.Value = 'hardlim';
            app.TestNetworkButton = uibutton(app.UIFigure, 'push');
            app.TestNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TestNetworkButtonPushed, true);
            app.TestNetworkButton.Text = 'Test Network';
            app.TrainNetworkButton = uibutton(app.UIFigure, 'push');
            app.TrainNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TrainNetworkButtonPushed, true);
            app.TrainNetworkButton.Text = 'Train Network';
            app.NumberoflayersDropDownLabel = uilabel(app.UIFigure);
            app.NumberoflayersDropDownLabel.Text = 'Number of layers';
            app.NumberoflayersDropDown = uidropdown(app.UIFigure);
            app.NumberoflayersDropDown.Items = {'1 ', '2 '};
            app.NumberoflayersDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberoflayersDropDownValueChanged, true);
            app.NumberoflayersDropDown.Value = '1 ';
            app.SoftmaxCheckBox = uicheckbox(app.UIFigure);
            app.SoftmaxCheckBox.ValueChangedFcn = createCallbackFcn(app, @SoftmaxCheckBoxValueChanged, true);
            app.SoftmaxCheckBox.Text = 'Softmax';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end