classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InterfaceTab                    matlab.ui.container.Tab
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Aprendizagemcomputacional20232024Label  matlab.ui.control.Label
        Assignmentn2PredictionanddetectionofepilepticseizuresLabel  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        ShallowNeuralNetworksTab        matlab.ui.container.Tab
        TestatrainedneuralnetworkPanel_3  matlab.ui.container.Panel
        SelectaTrainedNNButton_5        matlab.ui.control.Button
        Create_ML_Button_5              matlab.ui.control.Button
        PerformancePanel_3              matlab.ui.container.Panel
        SeizuresdetectedLabel_3         matlab.ui.control.Label
        SeizuresdetectedEditField_3     matlab.ui.control.NumericEditField
        SpecificityMLdet_3              matlab.ui.control.NumericEditField
        SensitivityMLdet_3              matlab.ui.control.NumericEditField
        DetectionLabel_3                matlab.ui.control.Label
        PredictionLabel_3               matlab.ui.control.Label
        SpecificityLabel_3              matlab.ui.control.Label
        SensitivityLabel_3              matlab.ui.control.Label
        SensitivityMLprev_3             matlab.ui.control.NumericEditField
        SpecificityMLprev_3             matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        CreateandtestaneuralnetworkPanel  matlab.ui.container.Panel
        TrainingstyleDropDown           matlab.ui.control.DropDown
        TrainingstyleDropDownLabel      matlab.ui.control.Label
        TrainfunctionDropDown           matlab.ui.control.DropDown
        TrainfunctionDropDownLabel      matlab.ui.control.Label
        Create_ML_Button                matlab.ui.control.Button
        OptimizationDropDown            matlab.ui.control.DropDown
        OptimizationDropDownLabel       matlab.ui.control.Label
        HiddenlayersPanel               matlab.ui.container.Panel
        Button_3                        matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        actfunc_ML                      matlab.ui.control.EditField
        ActivationFunctionsEditField_3Label  matlab.ui.control.Label
        nneurons_ML                     matlab.ui.control.EditField
        NumberofneuronsEditFieldLabel   matlab.ui.control.Label
        NoofhiddenlayersEditField       matlab.ui.control.EditField
        NoofhiddenlayersEditFieldLabel  matlab.ui.control.Label
        LayersDelays1nEditField         matlab.ui.control.EditField
        LayersDelays1nEditFieldLabel    matlab.ui.control.Label
        NeuralNetworkDropDown           matlab.ui.control.DropDown
        NeuralNetworkDropDownLabel      matlab.ui.control.Label
        FeaturesDropDown                matlab.ui.control.DropDown
        FeaturesDropDownLabel           matlab.ui.control.Label
        PatientDropDown                 matlab.ui.control.DropDown
        PatientDropDownLabel            matlab.ui.control.Label
        ConvolutionalNeuralNetworkTab   matlab.ui.container.Tab
        actfunc_ML_2                    matlab.ui.control.EditField
        ActivationFunctionsEditField_3Label_2  matlab.ui.control.Label
        PoolinglayersPanel              matlab.ui.container.Panel
        Button_4                        matlab.ui.control.Button
        PoolingStrideSpinner            matlab.ui.control.Spinner
        PoolingStrideSpinnerLabel       matlab.ui.control.Label
        PoolingtypeDropDown             matlab.ui.control.DropDown
        PoolingtypeDropDownLabel        matlab.ui.control.Label
        TestatrainedneuralnetworkPanel_2  matlab.ui.container.Panel
        SelectaTrainedNNButton_6        matlab.ui.control.Button
        Create_ML_Button_4              matlab.ui.control.Button
        PerformancePanel_2              matlab.ui.container.Panel
        SeizuresdetectedLabel_2         matlab.ui.control.Label
        SeizuresdetectedEditField_2     matlab.ui.control.NumericEditField
        SpecificityMLdet_2              matlab.ui.control.NumericEditField
        SensitivityMLdet_2              matlab.ui.control.NumericEditField
        DetectionLabel_2                matlab.ui.control.Label
        PredictionLabel_2               matlab.ui.control.Label
        SpecificityLabel_2              matlab.ui.control.Label
        SensitivityLabel_2              matlab.ui.control.Label
        SensitivityMLprev_2             matlab.ui.control.NumericEditField
        SpecificityMLprev_2             matlab.ui.control.NumericEditField
        CreateandtestaneuralnetworkPanel_2  matlab.ui.container.Panel
        Create_ML_Button_3              matlab.ui.control.Button
        EpochsEditField                 matlab.ui.control.NumericEditField
        EpochsEditFieldLabel            matlab.ui.control.Label
        SolverDropDown                  matlab.ui.control.DropDown
        SolverDropDownLabel             matlab.ui.control.Label
        FeaturesDropDown_2              matlab.ui.control.DropDown
        FeaturesDropDown_2Label         matlab.ui.control.Label
        PatientDropDown_2               matlab.ui.control.DropDown
        PatientDropDown_2Label          matlab.ui.control.Label
        ConvolutionallayersPanel        matlab.ui.container.Panel
        Button_6                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        actfunc_ML_4                    matlab.ui.control.EditField
        ActivationFunctionsEditField_3Label_4  matlab.ui.control.Label
        actfunc_ML_3                    matlab.ui.control.EditField
        ActivationFunctionsEditField_3Label_3  matlab.ui.control.Label
        NumberConv_CNN                  matlab.ui.control.NumericEditField
        NumberofConvolutionlayersEditField_2Label  matlab.ui.control.Label
        ConvStrideSpinner               matlab.ui.control.Spinner
        ConvStrideSpinnerLabel          matlab.ui.control.Label
        LongShortTermMemoryTab          matlab.ui.container.Tab
        CreateandtestaneuralnetworkPanel_3  matlab.ui.container.Panel
        LayerDropDown                   matlab.ui.control.DropDown
        LayerDropDownLabel              matlab.ui.control.Label
        EpochsEditField_2               matlab.ui.control.NumericEditField
        EpochsEditField_2Label          matlab.ui.control.Label
        HiddenUnitsEditField            matlab.ui.control.NumericEditField
        HiddenUnitsEditFieldLabel       matlab.ui.control.Label
        SolverDropDown_2                matlab.ui.control.DropDown
        SolverDropDown_2Label           matlab.ui.control.Label
        Create_ML_Button_8              matlab.ui.control.Button
        FeaturesDropDown_3              matlab.ui.control.DropDown
        FeaturesDropDown_3Label         matlab.ui.control.Label
        PatientDropDown_3               matlab.ui.control.DropDown
        PatientDropDown_3Label          matlab.ui.control.Label
        TestatrainedneuralnetworkPanel_4  matlab.ui.container.Panel
        SelectaTrainedNNButton_7        matlab.ui.control.Button
        Create_ML_Button_7              matlab.ui.control.Button
        PerformancePanel_4              matlab.ui.container.Panel
        SeizuresdetectedLabel           matlab.ui.control.Label
        SeizuresdetectedEditField_4     matlab.ui.control.NumericEditField
        SpecificityMLdet_4              matlab.ui.control.NumericEditField
        SensitivityMLdet_4              matlab.ui.control.NumericEditField
        DetectionLabel_4                matlab.ui.control.Label
        PredictionLabel_4               matlab.ui.control.Label
        SpecificityLabel_4              matlab.ui.control.Label
        SensitivityLabel_4              matlab.ui.control.Label
        SensitivityMLprev_4             matlab.ui.control.NumericEditField
        SpecificityMLprev_4             matlab.ui.control.NumericEditField
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        Menu_2                          matlab.ui.container.Menu
        Menu2_2                         matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Property % Description
        
        fileNN="";
        path="";
        seizures_detectedT;
        accuracyT;
        det_SET;
        det_SPT;
        prev_SET;
        prev_SPT;
        seizures_predictedT;

        %initialize variables in Shallow Neural Networks
        patientSNN = '63502';
        featuresSNN = 29;
        neuralNetworkSNN = "FFN";
        n_hiddenLayersSNN = 1;
        n_neuronsSNN = 20;
        training_styleSNN = "Incremental learning";
        trainFunctionSNN = "traingd";
        activationFunctions = "purelin";
        delaySNN;
        optimizationSNN;

        %initialize variables in Convolutional Neural Networks
        patientCNN="63502";
        featuresCNN=29;
        solverCNN ='adam';
        epochsCNN = 50;
        n_convLayersCNN = 1;
        n_filtersCNN;
        size_filtersCNN 
        convStride = 1;
        typePooling = 'max';
        size_layer;
        poolingStrideCNN = 1;

        %initilizae variables in Long short-term memory
        patientLSTM = "63502";
        featuresLSTM = 29;
        solverLSTM = 'adam';
        hiddenUnitsLSTM = 100;
        epochsLSTM = 50;
        layer = 1;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FeaturesDropDown
        function FeaturesDropDownValueChanged(app, event)
            value = app.FeaturesDropDown.Value;
            app.featuresSNN=value;
            
        end

        % Value changed function: NeuralNetworkDropDown
        function NeuralNetworkDropDownValueChanged(app, event)
            value = app.NeuralNetworkDropDown.Value;
            app.neuralNetworkSNN=string(value);
            disp(app.neuralNetworkSNN);
        end

        % Value changed function: LayersDelays1nEditField
        function LayersDelays1nEditFieldValueChanged(app, event)
            value = app.LayersDelays1nEditField.Value;
            app.delaySNN=value;
            
        end

        % Value changed function: OptimizationDropDown
        function OptimizationDropDownValueChanged(app, event)
            value = app.OptimizationDropDown.Value;
            
            if isequal(value, 'None')
                app.optimizationSNN=0;
                
            elseif isequal(value, 'Parallel')
                app.optimizationSNN=1;
                
            elseif isequal(value, 'GPU')
                app.optimizationSNN=2;
                
            elseif isequal(value, 'GPU + Parallel')
                app.optimizationSNN=3;
                
            end
        end

        % Value changed function: TrainfunctionDropDown
        function TrainfunctionDropDownValueChanged(app, event)
            value = app.TrainfunctionDropDown.Value;
            app.trainFunctionSNN=value;
            
        end

        % Value changed function: TrainingstyleDropDown
        function TrainingstyleDropDownValueChanged(app, event)
            value = app.TrainingstyleDropDown.Value;
            
            switch value
                case "Incremental learning"
                    app.training_styleSNN="IL";
                case "Batch training"
                    app.training_styleSNN="BT";
            end
        end

        % Value changed function: actfunc_ML
        function actfunc_MLValueChanged(app, event)
            value = app.actfunc_ML.Value;
            nlayer = app.n_hiddenLayersSNN;

            % Inicialize um array vazio para armazenar as funções de ativação
            activation_functions = strings(1, nlayer);

            for i = 1:nlayer
                actfun = split(value, ',');
                % Armazene cada função de ativação no array
                activation_functions(i) = actfun{i};
            end
            
            % Agora, activation_functions é um array de caracteres contendo
            % as funções de ativação
            app.activationFunctions=activation_functions;
        end

        % Value changed function: nneurons_ML
        function nneurons_MLValueChanged(app, event)
           value = app.nneurons_ML.Value;
           app.n_neuronsSNN=value;
           nlayer=app.n_hiddenLayersSNN;
           neurons = strings(1, nlayer);
          
            for i = 1:nlayer
                neurons_split = split(value, ',');
                % Armazene cada função de ativação no array
                neurons(i) = neurons_split{i};
            end
            
            % Agora, activation_functions é um array de caracteres contendo
            % as funções de ativação
            app.n_neuronsSNN=neurons;
           disp(app.n_neuronsSNN);
        end

        % Value changed function: NoofhiddenlayersEditField
        function NoofhiddenlayersEditFieldValueChanged(app, event)
            value = app.NoofhiddenlayersEditField.Value;
            % Converta para número de ponto flutuante
float = str2double(value);

% Verifique se a conversão foi bem-sucedida e se o número é um inteiro
if isnan(float) || float ~= round(float)
    % Se não for um número inteiro, exiba uma mensagem de erro
    error('Erro: O valor inserido não é um número inteiro válido.');
end

% Se tudo estiver correto, atribua o valor convertido à propriedade
app.n_hiddenLayersSNN = round(float);
            
        end

        % Value changed function: PatientDropDown
        function PatientDropDownValueChanged(app, event)
            value = app.PatientDropDown.Value;
            app.patientSNN=value;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            warndlg({'Coloque o número de camadas ocultas (1 ou 2)';...
                    },...
                    'Help')
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            warndlg({'Coloque o número de neurónios por cada camada separados por vírgulas.';...
                    'Ter em atenção que deve haver tantos quanto o número de camadas.'},...
                    'Help')
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            warndlg({'Insira as funções de ativação (purelin,tansig,logsig) das respetivas camadas separadas por vírgulas.';...
                    'Ter em atenção que deve haver tantas quanto o número de camadas.'},...
                    'Help')
        end

        % Value changed function: PatientDropDown_2
        function PatientDropDown_2ValueChanged(app, event)
            value = app.PatientDropDown_2.Value;
            app.patientCNN=value;
            disp(app.patientCNN);
        end

        % Value changed function: FeaturesDropDown_2
        function FeaturesDropDown_2ValueChanged(app, event)
            value = app.FeaturesDropDown_2.Value;
            app.featuresCNN=value;
        end

        % Value changed function: SolverDropDown
        function SolverDropDownValueChanged(app, event)
            value = app.SolverDropDown.Value;
            app.solverCNN=value;
            disp(app.solverCNN);
        end

        % Value changed function: EpochsEditField
        function EpochsEditFieldValueChanged(app, event)
            value = app.EpochsEditField.Value;
            app.epochsCNN=value;
        end

        % Value changed function: NumberConv_CNN
        function NumberConv_CNNValueChanged(app, event)
            value = app.NumberConv_CNN.Value;
            app.n_convLayersCNN=value;
        end

        % Callback function
        function NooffiltersineachlayerEditFieldValueChanged(app, event)
            value = app.NooffiltersineachlayerEditField.Value;
            app.n_filtersCNN=value;
        end

        % Callback function
        function SizeoffiltersEditFieldValueChanged(app, event)
            value = app.SizeoffiltersEditField.Value;
            app.size_filtersCNN=value;
        end

        % Value changed function: ConvStrideSpinner
        function ConvStrideSpinnerValueChanged(app, event)
            value = app.ConvStrideSpinner.Value;
            app.convStride=value;
        end

        % Value changed function: PoolingtypeDropDown
        function PoolingtypeDropDownValueChanged(app, event)
            value = app.PoolingtypeDropDown.Value;
            app.typePooling=value;
        end

        % Callback function
        function SizeforeachlayerEditFieldValueChanged(app, event)
            value = app.SizeforeachlayerEditField.Value;
            app.size_layer=value;

            nlayer = app.n_convLayersCNN

            % Inicialize um array vazio para armazenar as funções de ativação
            size = strings(1, nlayer);

            for i = 1:nlayer
                split_values = split(value, ',');
                % Armazene cada função de ativação no array
                size(i) = split_values{i};
            end
            
            % Agora, activation_functions é um array de caracteres contendo
            % as funções de ativação
            app.size_layer=size;
            disp(app.size_layer);
        end

        % Value changed function: PoolingStrideSpinner
        function PoolingStrideSpinnerValueChanged(app, event)
            value = app.PoolingStrideSpinner.Value;
            app.poolingStrideCNN=value;
        end

        % Value changed function: PatientDropDown_3
        function PatientDropDown_3ValueChanged(app, event)
            value = app.PatientDropDown_3.Value;
            app.patientLSTM=value;
        end

        % Value changed function: FeaturesDropDown_3
        function FeaturesDropDown_3ValueChanged(app, event)
            value = app.FeaturesDropDown_3.Value;
            float = str2double(value);
            app.featuresLSTM = round(float);
        end

        % Value changed function: SolverDropDown_2
        function SolverDropDown_2ValueChanged(app, event)
            value = app.SolverDropDown_2.Value;
            app.solverLSTM=value;
        end

        % Value changed function: HiddenUnitsEditField
        function HiddenUnitsEditFieldValueChanged(app, event)
            value = app.HiddenUnitsEditField.Value;
            app.hiddenUnitsLSTM=value;
        end

        % Value changed function: EpochsEditField_2
        function EpochsEditField_2ValueChanged(app, event)
            value = app.EpochsEditField_2.Value;
            app.epochsLSTM=value;
            
        end

        % Button pushed function: Create_ML_Button_8
        function Create_ML_Button_8Pushed(app, event)
            %Train and Test LSTM 
            [prev_SE, prev_SP, det_SE, det_SP, seizures_detected, seizures_predicted,accuracy] = main_train(app.patientLSTM, app.featuresLSTM, 'train', 'LSTM', app.solverLSTM, app.epochsLSTM, ...
    app.n_convLayersCNN, app.size_filtersCNN, app.n_filtersCNN, app.convStride, app.typePooling,  app.size_layer, app.poolingStrideCNN, ...
    app.hiddenUnitsLSTM, app.layer, ...
    app.n_hiddenLayersSNN,app.n_neuronsSNN, app.training_styleSNN, app.trainFunctionSNN, app.activationFunctions, app.delaySNN, app.optimizationSNN);
      
            app.SeizuresdetectedEditField_4.Value=seizures_detected;
            app.SensitivityMLprev_4.Value=prev_SE;
            app.SpecificityMLprev_4.Value=prev_SP;
            app.SensitivityMLdet_4.Value=det_SE;
            app.SpecificityMLdet_4.Value=det_SP;
        end

        % Callback function
        function LayerEditFieldValueChanged(app, event)
            value = app.LayerEditField.Value;
            app.layer=value;
        end

        % Button pushed function: Create_ML_Button_3
        function Create_ML_Button_3Pushed(app, event)
            %Train and Test CNN

            [prev_SE, prev_SP, det_SE, det_SP, seizures_detected, seizures_predicted,accuracy] = main_train(app.patientCNN, app.featuresCNN, 'train', 'CNN', app.solverCNN, app.epochsCNN, ...
    app.n_convLayersCNN, app.size_filtersCNN, app.n_filtersCNN, app.convStride, app.typePooling,  app.size_layer, app.poolingStrideCNN, ...
    app.hiddenUnitsLSTM, app.layer, ...
    app.n_hiddenLayersSNN,app.n_neuronsSNN, app.training_styleSNN, app.trainFunctionSNN, app.activationFunctions, app.delaySNN, app.optimizationSNN);

            app.SeizuresdetectedEditField_2.Value=seizures_detected;
            app.SensitivityMLprev_2.Value=prev_SE;
            app.SpecificityMLprev_2.Value=prev_SP;
            app.SensitivityMLdet_2.Value=det_SE;
            app.SpecificityMLdet_2.Value=det_SP;


        end

        % Button pushed function: SelectaTrainedNNButton_5
        function SelectaTrainedNNButton_5Pushed(app, event)
           pathLSTM = 'Trained Networks\SNN';

% Use uigetfile com 'DefaultPath' para limitar a escolha de arquivos ao diretório desejado
[filename, path] = uigetfile(fullfile(pathLSTM, '*.mat'), 'Escolha um arquivo MAT', pathLSTM);

% Verifique se o usuário selecionou um arquivo
if filename ~= 0
    % Execute o seu código com o arquivo selecionado
    [prev_SE, prev_SP, det_SE, det_SP, seizures_detected, seizures_predicted, accuracy] = main_test(filename);

    % Atribua os resultados às propriedades do seu aplicativo
    app.seizures_detectedT = seizures_detected;
    app.seizures_predictedT = seizures_predicted;
    app.accuracyT = accuracy;
    app.det_SET = det_SE;
    app.det_SPT = det_SP;
    app.prev_SET = prev_SE;
    app.prev_SPT = prev_SP;
   
else
    % O usuário cancelou a seleção de arquivo ou nenhum arquivo foi escolhido
    disp('Nenhum arquivo selecionado.');
end
            
        end

        % Button pushed function: Create_ML_Button_5
        function Create_ML_Button_5Pushed(app, event)
            app.SeizuresdetectedEditField_3.Value=app.seizures_detectedT;
            app.SensitivityMLprev_3.Value=app.prev_SET;
            app.SpecificityMLprev_3.Value=app.prev_SPT;
            app.SensitivityMLdet_3.Value=app.det_SET;
            app.SpecificityMLdet_3.Value=app.det_SPT;
 
        end

        % Button pushed function: SelectaTrainedNNButton_6
        function SelectaTrainedNNButton_6Pushed(app, event)
             pathLSTM = 'Trained Networks\CNN';

% Use uigetfile com 'DefaultPath' para limitar a escolha de arquivos ao diretório desejado
[filename, path] = uigetfile(fullfile(pathLSTM, '*.mat'), 'Escolha um arquivo MAT', pathLSTM);

% Verifique se o usuário selecionou um arquivo
if filename ~= 0
    % Execute o seu código com o arquivo selecionado
    [prev_SE, prev_SP, det_SE, det_SP, seizures_detected, seizures_predicted, accuracy] = main_test(filename);

    % Atribua os resultados às propriedades do seu aplicativo
    app.seizures_detectedT = seizures_detected;
    app.seizures_predictedT = seizures_predicted;
    app.accuracyT = accuracy;
    app.det_SET = det_SE;
    app.det_SPT = det_SP;
    app.prev_SET = prev_SE;
    app.prev_SPT = prev_SP;
else
    % O usuário cancelou a seleção de arquivo ou nenhum arquivo foi escolhido
    disp('Nenhum arquivo selecionado.');
end
        end

        % Button pushed function: Create_ML_Button_4
        function Create_ML_Button_4Pushed(app, event)
            app.SeizuresdetectedEditField_2.Value=app.seizures_detectedT;
            app.SensitivityMLprev_2.Value=app.prev_SET;
            app.SpecificityMLprev_2.Value=app.prev_SPT;
            app.SensitivityMLdet_2.Value=app.det_SET;
            app.SpecificityMLdet_2.Value=app.det_SPT;
          
        end

        % Button pushed function: SelectaTrainedNNButton_7
        function SelectaTrainedNNButton_7Pushed(app, event)
           % Especifique o diretório desejado
pathLSTM = 'Trained Networks\LSTM';

% Use uigetfile com 'DefaultPath' para limitar a escolha de arquivos ao diretório desejado
[filename, path] = uigetfile(fullfile(pathLSTM, '*.mat'), 'Escolha um arquivo MAT', pathLSTM);

% Verifique se o usuário selecionou um arquivo
if filename ~= 0
    % Execute o seu código com o arquivo selecionado
    [prev_SE, prev_SP, det_SE, det_SP, seizures_detected, seizures_predicted, accuracy] = main_test(filename);

    % Atribua os resultados às propriedades do seu aplicativo
    app.seizures_detectedT = seizures_detected;
    app.seizures_predictedT = seizures_predicted;
    app.accuracyT = accuracy;
    app.det_SET = det_SE;
    app.det_SPT = det_SP;
    app.prev_SET = prev_SE;
    app.prev_SPT = prev_SP;
else
    % O usuário cancelou a seleção de arquivo ou nenhum arquivo foi escolhido
    disp('Nenhum arquivo selecionado.');
end
        end

        % Button pushed function: Create_ML_Button_7
        function Create_ML_Button_7Pushed(app, event)
            app.SeizuresdetectedEditField_4.Value=app.seizures_detectedT;
            app.SensitivityMLprev_4.Value=app.prev_SET;
            app.SpecificityMLprev_4.Value=app.prev_SPT;
            app.SensitivityMLdet_4.Value=app.det_SET;
            app.SpecificityMLdet_4.Value=app.det_SPT;
           
        end

        % Button pushed function: Create_ML_Button
        function Create_ML_ButtonPushed(app, event)
            %Train and test SNN

            % Lista das funções permitidas
            allowed_func = {'purelin', 'logsig', 'tansig'};
            
            % Verifique se todas as funções em activationFunctions estão na lista de funções permitidas
            if all(ismember(string(app.activationFunctions), allowed_func)) 
                disp(app.activationFunctions);
            else
                % Se pelo menos uma função não for válida, exiba uma mensagem de erro
                error('Erro: Pelo menos uma função de ativação não é válida.');
            end

            [prev_SE, prev_SP, det_SE, det_SP, seizures_detected, seizures_predicted,accuracy] = main_train(app.patientSNN, app.featuresSNN, 'train', app.neuralNetworkSNN, app.solverCNN, app.epochsCNN, ...
    app.n_convLayersCNN, app.size_filtersCNN, app.n_filtersCNN, app.convStride, app.typePooling,  app.size_layer, app.poolingStrideCNN, ...
    app.hiddenUnitsLSTM, app.layer, ...
    app.n_hiddenLayersSNN,app.n_neuronsSNN, app.training_styleSNN, app.trainFunctionSNN, app.activationFunctions, app.delaySNN, app.optimizationSNN);
        
            app.SeizuresdetectedEditField_3.Value=seizures_detected;
            app.SensitivityMLprev_3.Value=prev_SE;
            app.SpecificityMLprev_3.Value=prev_SP;
            app.SensitivityMLdet_3.Value=det_SE;
            app.SpecificityMLdet_3.Value=det_SP;


        end

        % Value changed function: actfunc_ML_2
        function actfunc_ML_2ValueChanged(app, event)
           value = app.actfunc_ML_2.Value;
app.size_layer = value;

nlayer = app.n_convLayersCNN;

% Inicialize o array com células vazias
sizeArray = cell(1, nlayer);

split_values = split(value, ',');

for i = 1:nlayer
    % Armazene cada função de ativação na célula correspondente
    sizeArray{i} = split_values{i};
end

app.size_layer = sizeArray;

        end

        % Value changed function: actfunc_ML_3
        function actfunc_ML_3ValueChanged(app, event)
            value = app.actfunc_ML_3.Value;
            app.size_filtersCNN=value;

nlayer = app.n_convLayersCNN;

% Inicialize o array com células vazias
sizeArray = cell(1, nlayer);

split_values = split(value, ',');

for i = 1:nlayer
    % Armazene cada função de ativação na célula correspondente
    sizeArray{i} = split_values{i};
end

app.size_filtersCNN = sizeArray;

        end

        % Value changed function: actfunc_ML_4
        function actfunc_ML_4ValueChanged(app, event)
            value = app.actfunc_ML_4.Value;
            app.n_filtersCNN=value;

            nlayer = app.n_convLayersCNN;

% Inicialize o array com células vazias
sizeArray = cell(1, nlayer);

split_values = split(value, ',');

for i = 1:nlayer
    % Armazene cada função de ativação na célula correspondente
    sizeArray{i} = split_values{i};
end

app.n_filtersCNN = sizeArray;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
             warndlg({'Insira o número de filtros que pretende separados por vírgulas.';...
                    'Ter em atenção que deve haver tantos quanto o número de camadas.'},...
                    'Help')
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
             warndlg({'Insira o tamanho dos filtros das respetivas camadas separados por vírgulas.';...
                    'Ter em atenção que deve haver tantos quanto o número de camadas.'},...
                    'Help')
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
             warndlg({'Insira o tamanho da(s) "Pooling layer(s)"  separados por vírgulas.';...
                    'Ter em atenção que deve haver tantos quanto o número de camadas.'},...
                    'Help')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InterfaceTab = uitab(app.TabGroup);
            app.InterfaceTab.Title = 'Interface';
            app.TextArea = uitextarea(app.InterfaceTab);
            app.TextArea.Value = {'Duarte Ferreira (2020235393) | Cristiana Azevedo (2020221121)'; 'PL1, Group 3'};
            app.Assignmentn2PredictionanddetectionofepilepticseizuresLabel = uilabel(app.InterfaceTab);
            app.Assignmentn2PredictionanddetectionofepilepticseizuresLabel.Text = 'Assignment nº 2 – Prediction and detection of epileptic seizures';
            app.Aprendizagemcomputacional20232024Label = uilabel(app.InterfaceTab);
            app.Aprendizagemcomputacional20232024Label.Text = 'Aprendizagem computacional 2023/2024';
            app.Label_2 = uilabel(app.InterfaceTab);
            app.Label_2.Text = '______________________________________________________________________________________________';
            app.Label_3 = uilabel(app.InterfaceTab);
            app.Label_3.Text = '______________________________________________________________________________________________';
            app.ShallowNeuralNetworksTab = uitab(app.TabGroup);
            app.ShallowNeuralNetworksTab.Title = 'Shallow Neural Networks';
            app.CreateandtestaneuralnetworkPanel = uipanel(app.ShallowNeuralNetworksTab);
            app.CreateandtestaneuralnetworkPanel.Title = 'Create and test a neural network';
            app.PatientDropDownLabel = uilabel(app.CreateandtestaneuralnetworkPanel);
            app.PatientDropDownLabel.Text = 'Patient';
            app.PatientDropDown = uidropdown(app.CreateandtestaneuralnetworkPanel);
            app.PatientDropDown.Items = {'63502', '44202'};
            app.PatientDropDown.ValueChangedFcn = createCallbackFcn(app, @PatientDropDownValueChanged, true);
            app.PatientDropDown.Value = '63502';
            app.FeaturesDropDownLabel = uilabel(app.CreateandtestaneuralnetworkPanel);
            app.FeaturesDropDownLabel.Text = 'Features';
            app.FeaturesDropDown = uidropdown(app.CreateandtestaneuralnetworkPanel);
            app.FeaturesDropDown.Items = {'29', '15', '10', '3'};
            app.FeaturesDropDown.ValueChangedFcn = createCallbackFcn(app, @FeaturesDropDownValueChanged, true);
            app.FeaturesDropDown.Value = '29';
            app.NeuralNetworkDropDownLabel = uilabel(app.CreateandtestaneuralnetworkPanel);
            app.NeuralNetworkDropDownLabel.Text = 'Neural Network';
            app.NeuralNetworkDropDown = uidropdown(app.CreateandtestaneuralnetworkPanel);
            app.NeuralNetworkDropDown.Items = {'FFN', 'LRN'};
            app.NeuralNetworkDropDown.ValueChangedFcn = createCallbackFcn(app, @NeuralNetworkDropDownValueChanged, true);
            app.NeuralNetworkDropDown.Value = 'FFN';
            app.LayersDelays1nEditFieldLabel = uilabel(app.CreateandtestaneuralnetworkPanel);
            app.LayersDelays1nEditFieldLabel.Text = {'Layers Delays'; ' (1: n)'};
            app.LayersDelays1nEditField = uieditfield(app.CreateandtestaneuralnetworkPanel, 'text');
            app.LayersDelays1nEditField.ValueChangedFcn = createCallbackFcn(app, @LayersDelays1nEditFieldValueChanged, true);
            app.HiddenlayersPanel = uipanel(app.CreateandtestaneuralnetworkPanel);
            app.HiddenlayersPanel.Title = 'Hidden layers';
            app.NoofhiddenlayersEditFieldLabel = uilabel(app.HiddenlayersPanel);
            app.NoofhiddenlayersEditFieldLabel.Text = 'No. of hidden layers';
            app.NoofhiddenlayersEditField = uieditfield(app.HiddenlayersPanel, 'text');
            app.NoofhiddenlayersEditField.ValueChangedFcn = createCallbackFcn(app, @NoofhiddenlayersEditFieldValueChanged, true);
            app.NoofhiddenlayersEditField.Value = '1';
            app.NumberofneuronsEditFieldLabel = uilabel(app.HiddenlayersPanel);
            app.NumberofneuronsEditFieldLabel.Text = 'Number of neurons ';
            app.nneurons_ML = uieditfield(app.HiddenlayersPanel, 'text');
            app.nneurons_ML.ValueChangedFcn = createCallbackFcn(app, @nneurons_MLValueChanged, true);
            app.nneurons_ML.Value = '20';
            app.ActivationFunctionsEditField_3Label = uilabel(app.HiddenlayersPanel);
            app.ActivationFunctionsEditField_3Label.Text = 'Activation Function(s)';
            app.actfunc_ML = uieditfield(app.HiddenlayersPanel, 'text');
            app.actfunc_ML.ValueChangedFcn = createCallbackFcn(app, @actfunc_MLValueChanged, true);
            app.actfunc_ML.Value = 'purelin';
            app.Button = uibutton(app.HiddenlayersPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.Button_2 = uibutton(app.HiddenlayersPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '?';
            app.Button_3 = uibutton(app.HiddenlayersPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '?';
            app.OptimizationDropDownLabel = uilabel(app.CreateandtestaneuralnetworkPanel);
            app.OptimizationDropDownLabel.Text = 'Optimization';
            app.OptimizationDropDown = uidropdown(app.CreateandtestaneuralnetworkPanel);
            app.OptimizationDropDown.Items = {'None', 'Parallel', 'GPU', 'GPU+Parallel'};
            app.OptimizationDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationDropDownValueChanged, true);
            app.OptimizationDropDown.Value = 'None';
            app.Create_ML_Button = uibutton(app.CreateandtestaneuralnetworkPanel, 'push');
            app.Create_ML_Button.ButtonPushedFcn = createCallbackFcn(app, @Create_ML_ButtonPushed, true);
            app.Create_ML_Button.Text = 'TRAIN AND TEST';
            app.TrainfunctionDropDownLabel = uilabel(app.CreateandtestaneuralnetworkPanel);
            app.TrainfunctionDropDownLabel.Text = 'Train function';
            app.TrainfunctionDropDown = uidropdown(app.CreateandtestaneuralnetworkPanel);
            app.TrainfunctionDropDown.Items = {'traingd', 'traingda', 'traingdm', 'trainlm', 'trainscg'};
            app.TrainfunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @TrainfunctionDropDownValueChanged, true);
            app.TrainfunctionDropDown.Value = 'traingd';
            app.TrainingstyleDropDownLabel = uilabel(app.CreateandtestaneuralnetworkPanel);
            app.TrainingstyleDropDownLabel.Text = 'Training style';
            app.TrainingstyleDropDown = uidropdown(app.CreateandtestaneuralnetworkPanel);
            app.TrainingstyleDropDown.Items = {'Incremental learning', 'Batch training'};
            app.TrainingstyleDropDown.ValueChangedFcn = createCallbackFcn(app, @TrainingstyleDropDownValueChanged, true);
            app.TrainingstyleDropDown.Value = 'Incremental learning';
            app.Label = uilabel(app.ShallowNeuralNetworksTab);
            app.Label.Text = '';
            app.TestatrainedneuralnetworkPanel_3 = uipanel(app.ShallowNeuralNetworksTab);
            app.TestatrainedneuralnetworkPanel_3.Title = 'Test a trained neural network';
            app.PerformancePanel_3 = uipanel(app.TestatrainedneuralnetworkPanel_3);
            app.PerformancePanel_3.Title = 'Performance';
            app.SpecificityMLprev_3 = uieditfield(app.PerformancePanel_3, 'numeric');
            app.SensitivityMLprev_3 = uieditfield(app.PerformancePanel_3, 'numeric');
            app.SensitivityLabel_3 = uilabel(app.PerformancePanel_3);
            app.SensitivityLabel_3.Text = 'Sensitivity';
            app.SpecificityLabel_3 = uilabel(app.PerformancePanel_3);
            app.SpecificityLabel_3.Text = 'Specificity';
            app.PredictionLabel_3 = uilabel(app.PerformancePanel_3);
            app.PredictionLabel_3.Text = 'Prediction';
            app.DetectionLabel_3 = uilabel(app.PerformancePanel_3);
            app.DetectionLabel_3.Text = 'Detection';
            app.SensitivityMLdet_3 = uieditfield(app.PerformancePanel_3, 'numeric');
            app.SpecificityMLdet_3 = uieditfield(app.PerformancePanel_3, 'numeric');
            app.SeizuresdetectedEditField_3 = uieditfield(app.PerformancePanel_3, 'numeric');
            app.SeizuresdetectedEditField_3.Editable = 'off';
            app.SeizuresdetectedLabel_3 = uilabel(app.PerformancePanel_3);
            app.SeizuresdetectedLabel_3.Text = 'Seizures detected (%)';
            app.Create_ML_Button_5 = uibutton(app.TestatrainedneuralnetworkPanel_3, 'push');
            app.Create_ML_Button_5.ButtonPushedFcn = createCallbackFcn(app, @Create_ML_Button_5Pushed, true);
            app.Create_ML_Button_5.Text = 'TEST';
            app.SelectaTrainedNNButton_5 = uibutton(app.TestatrainedneuralnetworkPanel_3, 'push');
            app.SelectaTrainedNNButton_5.ButtonPushedFcn = createCallbackFcn(app, @SelectaTrainedNNButton_5Pushed, true);
            app.SelectaTrainedNNButton_5.Text = 'Select a Trained NN';
            app.ConvolutionalNeuralNetworkTab = uitab(app.TabGroup);
            app.ConvolutionalNeuralNetworkTab.Title = 'Convolutional Neural Network';
            app.CreateandtestaneuralnetworkPanel_2 = uipanel(app.ConvolutionalNeuralNetworkTab);
            app.CreateandtestaneuralnetworkPanel_2.Title = 'Create and test a neural network';
            app.ConvolutionallayersPanel = uipanel(app.CreateandtestaneuralnetworkPanel_2);
            app.ConvolutionallayersPanel.Title = 'Convolutional layers';
            app.ConvStrideSpinnerLabel = uilabel(app.ConvolutionallayersPanel);
            app.ConvStrideSpinnerLabel.Text = 'Conv Stride';
            app.ConvStrideSpinner = uispinner(app.ConvolutionallayersPanel);
            app.ConvStrideSpinner.ValueChangedFcn = createCallbackFcn(app, @ConvStrideSpinnerValueChanged, true);
            app.ConvStrideSpinner.Value = 1;
            app.NumberofConvolutionlayersEditField_2Label = uilabel(app.ConvolutionallayersPanel);
            app.NumberofConvolutionlayersEditField_2Label.Text = 'Number of Convolution layers';
            app.NumberConv_CNN = uieditfield(app.ConvolutionallayersPanel, 'numeric');
            app.NumberConv_CNN.ValueChangedFcn = createCallbackFcn(app, @NumberConv_CNNValueChanged, true);
            app.NumberConv_CNN.Value = 1;
            app.ActivationFunctionsEditField_3Label_3 = uilabel(app.ConvolutionallayersPanel);
            app.ActivationFunctionsEditField_3Label_3.Text = 'Size of filters';
            app.actfunc_ML_3 = uieditfield(app.ConvolutionallayersPanel, 'text');
            app.actfunc_ML_3.ValueChangedFcn = createCallbackFcn(app, @actfunc_ML_3ValueChanged, true);
            app.ActivationFunctionsEditField_3Label_4 = uilabel(app.ConvolutionallayersPanel);
            app.ActivationFunctionsEditField_3Label_4.Text = 'No. of filters in each layer';
            app.actfunc_ML_4 = uieditfield(app.ConvolutionallayersPanel, 'text');
            app.actfunc_ML_4.ValueChangedFcn = createCallbackFcn(app, @actfunc_ML_4ValueChanged, true);
            app.Button_5 = uibutton(app.ConvolutionallayersPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '?';
            app.Button_6 = uibutton(app.ConvolutionallayersPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '?';
            app.PatientDropDown_2Label = uilabel(app.CreateandtestaneuralnetworkPanel_2);
            app.PatientDropDown_2Label.Text = 'Patient';
            app.PatientDropDown_2 = uidropdown(app.CreateandtestaneuralnetworkPanel_2);
            app.PatientDropDown_2.Items = {'63502', '44202'};
            app.PatientDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PatientDropDown_2ValueChanged, true);
            app.PatientDropDown_2.Value = '63502';
            app.FeaturesDropDown_2Label = uilabel(app.CreateandtestaneuralnetworkPanel_2);
            app.FeaturesDropDown_2Label.Text = 'Features';
            app.FeaturesDropDown_2 = uidropdown(app.CreateandtestaneuralnetworkPanel_2);
            app.FeaturesDropDown_2.Items = {'29', '15', '10', '3'};
            app.FeaturesDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FeaturesDropDown_2ValueChanged, true);
            app.FeaturesDropDown_2.Value = '29';
            app.SolverDropDownLabel = uilabel(app.CreateandtestaneuralnetworkPanel_2);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.CreateandtestaneuralnetworkPanel_2);
            app.SolverDropDown.Items = {'adam', 'sgdm', 'rmsprop'};
            app.SolverDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverDropDownValueChanged, true);
            app.SolverDropDown.Value = 'adam';
            app.EpochsEditFieldLabel = uilabel(app.CreateandtestaneuralnetworkPanel_2);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.CreateandtestaneuralnetworkPanel_2, 'numeric');
            app.EpochsEditField.ValueChangedFcn = createCallbackFcn(app, @EpochsEditFieldValueChanged, true);
            app.EpochsEditField.Value = 50;
            app.Create_ML_Button_3 = uibutton(app.CreateandtestaneuralnetworkPanel_2, 'push');
            app.Create_ML_Button_3.ButtonPushedFcn = createCallbackFcn(app, @Create_ML_Button_3Pushed, true);
            app.Create_ML_Button_3.Text = 'TRAIN AND TEST';
            app.TestatrainedneuralnetworkPanel_2 = uipanel(app.ConvolutionalNeuralNetworkTab);
            app.TestatrainedneuralnetworkPanel_2.Title = 'Test a trained neural network';
            app.PerformancePanel_2 = uipanel(app.TestatrainedneuralnetworkPanel_2);
            app.PerformancePanel_2.Title = 'Performance';
            app.SpecificityMLprev_2 = uieditfield(app.PerformancePanel_2, 'numeric');
            app.SensitivityMLprev_2 = uieditfield(app.PerformancePanel_2, 'numeric');
            app.SensitivityLabel_2 = uilabel(app.PerformancePanel_2);
            app.SensitivityLabel_2.Text = 'Sensitivity';
            app.SpecificityLabel_2 = uilabel(app.PerformancePanel_2);
            app.SpecificityLabel_2.Text = 'Specificity';
            app.PredictionLabel_2 = uilabel(app.PerformancePanel_2);
            app.PredictionLabel_2.Text = 'Prediction';
            app.DetectionLabel_2 = uilabel(app.PerformancePanel_2);
            app.DetectionLabel_2.Text = 'Detection';
            app.SensitivityMLdet_2 = uieditfield(app.PerformancePanel_2, 'numeric');
            app.SpecificityMLdet_2 = uieditfield(app.PerformancePanel_2, 'numeric');
            app.SeizuresdetectedEditField_2 = uieditfield(app.PerformancePanel_2, 'numeric');
            app.SeizuresdetectedEditField_2.Editable = 'off';
            app.SeizuresdetectedLabel_2 = uilabel(app.PerformancePanel_2);
            app.SeizuresdetectedLabel_2.Text = 'Seizures detected (%)';
            app.Create_ML_Button_4 = uibutton(app.TestatrainedneuralnetworkPanel_2, 'push');
            app.Create_ML_Button_4.ButtonPushedFcn = createCallbackFcn(app, @Create_ML_Button_4Pushed, true);
            app.Create_ML_Button_4.Text = 'PERFORMANCE';
            app.SelectaTrainedNNButton_6 = uibutton(app.TestatrainedneuralnetworkPanel_2, 'push');
            app.SelectaTrainedNNButton_6.ButtonPushedFcn = createCallbackFcn(app, @SelectaTrainedNNButton_6Pushed, true);
            app.SelectaTrainedNNButton_6.Text = 'Select a Trained NN';
            app.PoolinglayersPanel = uipanel(app.ConvolutionalNeuralNetworkTab);
            app.PoolinglayersPanel.Title = 'Pooling layers';
            app.PoolingtypeDropDownLabel = uilabel(app.PoolinglayersPanel);
            app.PoolingtypeDropDownLabel.Text = 'Pooling type';
            app.PoolingtypeDropDown = uidropdown(app.PoolinglayersPanel);
            app.PoolingtypeDropDown.Items = {'max', 'avg'};
            app.PoolingtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PoolingtypeDropDownValueChanged, true);
            app.PoolingtypeDropDown.Value = 'max';
            app.PoolingStrideSpinnerLabel = uilabel(app.PoolinglayersPanel);
            app.PoolingStrideSpinnerLabel.Text = 'Pooling Stride';
            app.PoolingStrideSpinner = uispinner(app.PoolinglayersPanel);
            app.PoolingStrideSpinner.ValueChangedFcn = createCallbackFcn(app, @PoolingStrideSpinnerValueChanged, true);
            app.PoolingStrideSpinner.Value = 1;
            app.Button_4 = uibutton(app.PoolinglayersPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '?';
            app.ActivationFunctionsEditField_3Label_2 = uilabel(app.ConvolutionalNeuralNetworkTab);
            app.ActivationFunctionsEditField_3Label_2.Text = 'Size for each layer';
            app.actfunc_ML_2 = uieditfield(app.ConvolutionalNeuralNetworkTab, 'text');
            app.actfunc_ML_2.ValueChangedFcn = createCallbackFcn(app, @actfunc_ML_2ValueChanged, true);
            app.LongShortTermMemoryTab = uitab(app.TabGroup);
            app.LongShortTermMemoryTab.Title = 'Long Short-Term Memory';
            app.TestatrainedneuralnetworkPanel_4 = uipanel(app.LongShortTermMemoryTab);
            app.TestatrainedneuralnetworkPanel_4.Title = 'Test a trained neural network';
            app.PerformancePanel_4 = uipanel(app.TestatrainedneuralnetworkPanel_4);
            app.PerformancePanel_4.Title = 'Performance';
            app.SpecificityMLprev_4 = uieditfield(app.PerformancePanel_4, 'numeric');
            app.SensitivityMLprev_4 = uieditfield(app.PerformancePanel_4, 'numeric');
            app.SensitivityLabel_4 = uilabel(app.PerformancePanel_4);
            app.SensitivityLabel_4.Text = 'Sensitivity';
            app.SpecificityLabel_4 = uilabel(app.PerformancePanel_4);
            app.SpecificityLabel_4.Text = 'Specificity';
            app.PredictionLabel_4 = uilabel(app.PerformancePanel_4);
            app.PredictionLabel_4.Text = 'Prediction';
            app.DetectionLabel_4 = uilabel(app.PerformancePanel_4);
            app.DetectionLabel_4.Text = 'Detection';
            app.SensitivityMLdet_4 = uieditfield(app.PerformancePanel_4, 'numeric');
            app.SpecificityMLdet_4 = uieditfield(app.PerformancePanel_4, 'numeric');
            app.SeizuresdetectedEditField_4 = uieditfield(app.PerformancePanel_4, 'numeric');
            app.SeizuresdetectedEditField_4.Editable = 'off';
            app.SeizuresdetectedLabel = uilabel(app.PerformancePanel_4);
            app.SeizuresdetectedLabel.Text = 'Seizures detected (%)';
            app.Create_ML_Button_7 = uibutton(app.TestatrainedneuralnetworkPanel_4, 'push');
            app.Create_ML_Button_7.ButtonPushedFcn = createCallbackFcn(app, @Create_ML_Button_7Pushed, true);
            app.Create_ML_Button_7.Text = 'TEST';
            app.SelectaTrainedNNButton_7 = uibutton(app.TestatrainedneuralnetworkPanel_4, 'push');
            app.SelectaTrainedNNButton_7.ButtonPushedFcn = createCallbackFcn(app, @SelectaTrainedNNButton_7Pushed, true);
            app.SelectaTrainedNNButton_7.Text = 'Select a Trained NN';
            app.CreateandtestaneuralnetworkPanel_3 = uipanel(app.LongShortTermMemoryTab);
            app.CreateandtestaneuralnetworkPanel_3.Title = 'Create and test a neural network';
            app.PatientDropDown_3Label = uilabel(app.CreateandtestaneuralnetworkPanel_3);
            app.PatientDropDown_3Label.Text = 'Patient';
            app.PatientDropDown_3 = uidropdown(app.CreateandtestaneuralnetworkPanel_3);
            app.PatientDropDown_3.Items = {'63502', '44202'};
            app.PatientDropDown_3.ValueChangedFcn = createCallbackFcn(app, @PatientDropDown_3ValueChanged, true);
            app.PatientDropDown_3.Value = '63502';
            app.FeaturesDropDown_3Label = uilabel(app.CreateandtestaneuralnetworkPanel_3);
            app.FeaturesDropDown_3Label.Text = 'Features';
            app.FeaturesDropDown_3 = uidropdown(app.CreateandtestaneuralnetworkPanel_3);
            app.FeaturesDropDown_3.Items = {'29', '15', '10', '3'};
            app.FeaturesDropDown_3.ValueChangedFcn = createCallbackFcn(app, @FeaturesDropDown_3ValueChanged, true);
            app.FeaturesDropDown_3.Value = '29';
            app.Create_ML_Button_8 = uibutton(app.CreateandtestaneuralnetworkPanel_3, 'push');
            app.Create_ML_Button_8.ButtonPushedFcn = createCallbackFcn(app, @Create_ML_Button_8Pushed, true);
            app.Create_ML_Button_8.Text = 'TRAIN AND TEST';
            app.SolverDropDown_2Label = uilabel(app.CreateandtestaneuralnetworkPanel_3);
            app.SolverDropDown_2Label.Text = 'Solver';
            app.SolverDropDown_2 = uidropdown(app.CreateandtestaneuralnetworkPanel_3);
            app.SolverDropDown_2.Items = {'adam', 'sgdm', 'rmsprop'};
            app.SolverDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SolverDropDown_2ValueChanged, true);
            app.SolverDropDown_2.Value = 'adam';
            app.HiddenUnitsEditFieldLabel = uilabel(app.CreateandtestaneuralnetworkPanel_3);
            app.HiddenUnitsEditFieldLabel.Text = 'Hidden Units';
            app.HiddenUnitsEditField = uieditfield(app.CreateandtestaneuralnetworkPanel_3, 'numeric');
            app.HiddenUnitsEditField.Limits = [0 Inf];
            app.HiddenUnitsEditField.ValueChangedFcn = createCallbackFcn(app, @HiddenUnitsEditFieldValueChanged, true);
            app.HiddenUnitsEditField.Value = 100;
            app.EpochsEditField_2Label = uilabel(app.CreateandtestaneuralnetworkPanel_3);
            app.EpochsEditField_2Label.Text = 'Epochs';
            app.EpochsEditField_2 = uieditfield(app.CreateandtestaneuralnetworkPanel_3, 'numeric');
            app.EpochsEditField_2.ValueChangedFcn = createCallbackFcn(app, @EpochsEditField_2ValueChanged, true);
            app.EpochsEditField_2.Value = 50;
            app.LayerDropDownLabel = uilabel(app.CreateandtestaneuralnetworkPanel_3);
            app.LayerDropDownLabel.Text = 'Layer';
            app.LayerDropDown = uidropdown(app.CreateandtestaneuralnetworkPanel_3);
            app.LayerDropDown.Items = {'1', '2'};
            app.LayerDropDown.Value = '1';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.actfunc_ML_4.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end